classdef app1_no_load < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BadRespiration                matlab.ui.control.Button
        UncertainRespiration          matlab.ui.control.Button
        GoodRespiration               matlab.ui.control.Button
        RespirationLabel              matlab.ui.control.Label
        BadCardiac                    matlab.ui.control.Button
        UncertainCardiac              matlab.ui.control.Button
        GoodCardiac                   matlab.ui.control.Button
        CardiacLabel                  matlab.ui.control.Label
        GoButton                      matlab.ui.control.Button
        InputSubjectNoEditField       matlab.ui.control.EditField
        InputSubjectNoEditFieldLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        UncertainButton               matlab.ui.control.Button
        BadButton                     matlab.ui.control.Button
        GoodButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        dirlist = dir('./physio_QA_plots');
        
        %my_path = '/data1/neurdylab/datasets/nki_rockland/plot/';
        my_path = '/Users/rachelyang/Desktop/111/'
        
        
        filepath = '';
        good_path = '';
        uncertain_path = '';
        bad_path = '';
        good_c_path = '';
        uncertain_c_path = '';
        bad_c_path = '';
        good_r_path = '';
        uncertain_r_path = '';
        bad_r_path = '';
        
%         filepath = '/Users/rachelyang/Desktop/111/physio_QA_plots/';
% 
%         good_path = '/Users/rachelyang/Desktop/111/good.txt';
%         uncertain_path = '/Users/rachelyang/Desktop/111/uncertain.txt';
%         bad_path = '/Users/rachelyang/Desktop/111/bad.txt';
%         
%         good_c_path = '/Users/rachelyang/Desktop/111/good_cardiac.txt';
%         uncertain_c_path = '/Users/rachelyang/Desktop/111/uncertain_cardiac.txt';
%         bad_c_path = '/Users/rachelyang/Desktop/111/bad_cardiac.txt';
%         
%         good_r_path = '/Users/rachelyang/Desktop/111/good_respiration.txt';
%         uncertain_r_path = '/Users/rachelyang/Desktop/111/uncertain_respiration.txt';
%         bad_r_path = '/Users/rachelyang/Desktop/111/bad_respiration.txt';
        
        count = 0;
        filenames = [];
        filecount = 0; % keep track of total file numbers
        i = 0; % used to iterate through files
        
        good = []
        uncertain = []
        bad = []
        
        good_c = []
        uncertain_c = []
        bad_c = []
        
        good_r = []
        uncertain_r = []
        bad_r = []
        
        sorted_or_not = []
        sorted_or_not_c = []
        sorted_or_not_r = []
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filepath = string(append(app.my_path, 'physio_QA_plots/'));
            app.good_path = string(append(app.my_path, 'good.txt'));
            app.uncertain_path = string(append(app.my_path, 'uncertain.txt'));
            app.bad_path = string(append(app.my_path, 'bad.txt'));
            
            app.good_r_path = string(append(app.my_path, 'good_respiration.txt'));
            app.uncertain_r_path = string(append(app.my_path, 'uncertain_respiration.txt'));
            app.bad_r_path = string(append(app.my_path, 'bad_respiration.txt'));
            
            app.good_c_path = string(append(app.my_path, 'good_cardiac.txt'));
            app.uncertain_c_path = string(append(app.my_path, 'uncertain_cardiac.txt'));
            app.bad_c_path = string(append(app.my_path, 'bad_cardiac.txt'));
            
            % update iterator
            app.i = app.i + 1;
        
            % load files and store file names in 'filenames'
            filelist = {app.dirlist.name};
            filelist_size = size(filelist, 2);
            app.filenames = filelist(:, 3:filelist_size);
            app.filecount = size(app.filenames, 2);
        
            % set image to first file
            path = string(append(app.filepath, app.filenames(1, app.i)));
            %app.Label2.Text = path; %%%% debugging %%%%%
            app.Image.ImageSource = path;
        
        
            % set label to subject label
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            app.Label.Text = cur_filename;
        
            % udpate sorted_or_not array
            app.sorted_or_not = ones(1, app.filecount)*false;
            app.sorted_or_not_c = ones(1, app.filecount)*false;
            app.sorted_or_not_r = ones(1, app.filecount)*false;
            
            
            % --- read output txt files ---
            if isfile(app.good_path)
                app.good = readlines(app.good_path);
                app.good = app.good';
                app.good = unique(app.good);
                app.good = strtrim(app.good);
            end
            
            if isfile(app.uncertain_path)
                app.uncertain = readlines(app.uncertain_path);
                app.uncertain = app.uncertain';
                app.uncertain = unique(app.uncertain);
                app.uncertain = strtrim(app.uncertain);
            end
            
            if isfile(app.bad_path)
                app.bad = readlines(app.bad_path);
                app.bad = app.bad';
                app.bad = unique(app.bad);
                app.bad = strtrim(app.bad);
            end
            
            if isfile(app.good_c_path)
                app.good_c = readlines(app.good_c_path);
                app.good_c = app.good_c';
                app.good_c = unique(app.good_c);
                app.good_c = strtrim(app.good_c);
            end
            
            if isfile(app.uncertain_c_path)
                app.uncertain_c = readlines(app.uncertain_c_path);
                app.uncertain_c = app.uncertain_c';
                app.uncertain_c = unique(app.uncertain_c);
                app.uncertain_c = strtrim(app.uncertain_c);
            end
            
            if isfile(app.bad_c_path)
                app.bad_c = readlines(app.bad_c_path);
                app.bad_c = app.bad_c';
                app.bad_c = unique(app.bad_c);
                app.bad_c = strtrim(app.bad_c);
            end
            
            %---
            if isfile(app.good_r_path)
                app.good_r = readlines(app.good_r_path);
                app.good_r = app.good_r';
                app.good_r = unique(app.good_r);
                app.good_r = strtrim(app.good_r);
            end
            
            if isfile(app.uncertain_c_path)
                app.uncertain_r = readlines(app.uncertain_r_path);
                app.uncertain_r = app.uncertain_r';
                app.uncertain_r = unique(app.uncertain_r);
                app.uncertain_r = strtrim(app.uncertain_r);
            end
            
            if isfile(app.bad_r_path)
                app.bad_r = readlines(app.bad_r_path);
                app.bad_r = app.bad_r';
                app.bad_r = unique(app.bad_r);
                app.bad_r = strtrim(app.bad_r);
            end
                
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.i <= (app.filecount - 1)
                % update iterator
                app.i = app.i + 1;
            
                % set image to first file
                path = string(append(app.filepath, app.filenames(1, app.i)));
                %app.Label2.Text = path;
                app.Image.ImageSource = path;
        
                % set label to subject label
                cur_filename = char(app.filenames(1, app.i));
                cur_filename = cur_filename(1:13);
                app.Label.Text = cur_filename;
                
            % if reached last file, 
            elseif app.i == app.filecount
                app.Label.Text = 'This is the last file';
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if app.i >= 2
                % update iterator
                app.i = app.i - 1;
            
                % set image to first file
                path = string(append(app.filepath, app.filenames(1, app.i)));
                %app.Label2.Text = path;
                app.Image.ImageSource = path;
        
                % set label to subject label
                cur_filename = char(app.filenames(1, app.i));
                cur_filename = cur_filename(1:13);
                app.Label.Text = cur_filename;
                
            % if reached last file, 
            elseif app.i == 1
                app.Label.Text = 'This is the first file';
            end
        end

        % Button pushed function: GoodButton
        function GoodButtonPushed(app, event)
            
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the good files
            app.good = [app.good, string(cur_filename)];
            app.good = unique(app.good,'stable');
             
            % write the array to output file 'good.txt'
            fileID = fopen('good.txt', 'w');
            fprintf(fileID,'%s \n',app.good{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in bad or unceratin
            if app.sorted_or_not(app.i) == true
                
                % check that app.bad is not empty
                if ~isempty(app.bad)
                    
                    % find index of this file in app.bad
                    index1 = find(app.bad==string(cur_filename));
                    %app.Label3.Text = string(index1);
                    
                    % if this file is in app.bad, delete it in app.bad
                    if ~isempty(index1)
                        app.bad(index1) = [];
                    end
                    
                    % update bad.txt
                    fileID = fopen('bad.txt', 'w');
                    fprintf(fileID,'%s \n',app.bad{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.uncertain)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.uncertain==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.uncertain(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('uncertain.txt', 'w');
                    fprintf(fileID,'%s \n',app.uncertain{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not(app.i) = true;


        end

        % Button pushed function: UncertainButton
        function UncertainButtonPushed(app, event)
           
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the uncertain files
            app.uncertain = [app.uncertain, string(cur_filename)];
            app.uncertain = unique(app.uncertain,'stable');
            
            % write the array to output file 'uncertain.txt'
            fileID = fopen('uncertain.txt', 'w');
            fprintf(fileID,'%s \n',app.uncertain{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in bad or unceratin
            if app.sorted_or_not(app.i) == true
                
                % check that app.bad is not empty
                if ~isempty(app.bad)
                    
                    % find index of this file in app.bad
                    index1 = find(app.bad==string(cur_filename));
                    %app.Label3.Text = string(index1);
                    
                    % if this file is in app.bad, delete it in app.bad
                    if ~isempty(index1)
                        app.bad(index1) = [];
                    end
                    
                    % update bad.txt
                    fileID = fopen('bad.txt', 'w');
                    fprintf(fileID,'%s \n',app.bad{:});
                    fclose(fileID);
                    
                end
                
                % check that app.good is not empty
                if ~isempty(app.good)
                    
                    % find index of this file in app.good
                    index2 = find(app.good==string(cur_filename));
                    
                    % if this file is in app.good, delete it in
                    % app.good
                    if ~isempty(index2)
                       app.good(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('good.txt', 'w');
                    fprintf(fileID,'%s \n',app.good{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not(app.i) = true;
            
            
        end

        % Button pushed function: BadButton
        function BadButtonPushed(app, event)
            
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the bad files
            app.bad = [app.bad, string(cur_filename)];
            app.bad = unique(app.bad,'stable');
            
            % write the array to output file 'bad.txt'
            fileID = fopen('bad.txt', 'w');
            fprintf(fileID,'%s \n',app.bad{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or unceratin
            if app.sorted_or_not(app.i) == true
                
                % check that app.good is not empty
                if ~isempty(app.good)
                    
                    % find index of this file in app.good
                    index1 = find(app.good==string(cur_filename));
                    
                    % if this file is in app.good, delete it in app.good
                    if ~isempty(index1)
                        app.good(index1) = [];
                    end
                    
                    % update good.txt
                    fileID = fopen('good.txt', 'w');
                    fprintf(fileID,'%s \n',app.good{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.uncertain)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.uncertain==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.uncertain(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('uncertain.txt', 'w');
                    fprintf(fileID,'%s \n',app.uncertain{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not(app.i) = true;
            
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            value = app.InputSubjectNoEditField.Value;
            
            %app.Label2.Text = value;
            if ~isempty(app.filenames)
                
                tmp = find(contains(app.filenames, value));
            
                if isempty(tmp)
                    app.Label.Text = "WRONG FILE NAME";
                else
                    
                    app.i = tmp;
                    
                    % set image to first file
                    path = string(append(app.filepath, app.filenames(1, app.i)));
                    app.Image.ImageSource = path;
            
                    % set label to subject label
                    cur_filename = char(app.filenames(1, app.i));
                    cur_filename = cur_filename(1:13);
                    app.Label.Text = cur_filename;
                end
            else
                app.Label.Text = "LOAD FILES FIRST!";
            end
            
        end

        % Button pushed function: GoodCardiac
        function GoodCardiacButtonPushed(app, event)
            
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the bad files
            app.good_c = [app.good_c, string(cur_filename)];
            app.good_c = unique(app.good_c,'stable');
            
            % write the array to output file 'bad.txt'
            fileID = fopen('good_cardiac.txt', 'w');
            fprintf(fileID,'%s \n',app.good_c{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or unceratin
            if app.sorted_or_not_c(app.i) == true
                
                % check that app.good is not empty
                if ~isempty(app.uncertain_c)
                    
                    % find index of this file in app.good
                    index1 = find(app.uncertain_c==string(cur_filename));
                    
                    % if this file is in app.good, delete it in app.good
                    if ~isempty(index1)
                        app.uncertain_c(index1) = [];
                    end
                    
                    % update good.txt
                    fileID = fopen('uncertain_cardiac.txt', 'w');
                    fprintf(fileID,'%s \n',app.uncertain_c{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.bad_c)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.bad_c==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.bad_c(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('bad_cardiac.txt', 'w');
                    fprintf(fileID,'%s \n',app.bad_c{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not_c(app.i) = true;


        end

        % Button pushed function: BadCardiac
        function BadCardiacButtonPushed(app, event)
            
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the bad files
            app.bad_c = [app.bad_c, string(cur_filename)];
            app.bad_c = unique(app.bad_c,'stable');
            
            % write the array to output file 'bad.txt'
            fileID = fopen('bad_cardiac.txt', 'w');
            fprintf(fileID,'%s \n',app.bad_c{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or unceratin
            if app.sorted_or_not_c(app.i) == true
                
                % check that app.good is not empty
                if ~isempty(app.good_c)
                    
                    % find index of this file in app.good
                    index1 = find(app.good_c==string(cur_filename));
                    
                    % if this file is in app.good, delete it in app.good
                    if ~isempty(index1)
                        app.good_c(index1) = [];
                    end
                    
                    % update good.txt
                    fileID = fopen('good_cardiac.txt', 'w');
                    fprintf(fileID,'%s \n',app.good_c{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.uncertain_c)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.uncertain_c==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.uncertain_c(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('uncertain_cardiac.txt', 'w');
                    fprintf(fileID,'%s \n',app.uncertain_c{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not_c(app.i) = true;


        end

        % Button pushed function: UncertainCardiac
        function UncertainCardiacButtonPushed(app, event)
            
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the uncertain files
            app.uncertain_c = [app.uncertain_c, string(cur_filename)];
            app.uncertain_c = unique(app.uncertain_c,'stable');
            
            % write the array to output file 'uncertain_cardiac.txt'
            fileID = fopen('uncertain_cardiac.txt', 'w');
            fprintf(fileID,'%s \n',app.uncertain_c{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or bad
            if app.sorted_or_not_c(app.i) == true
                
                % check that app.good_c is not empty
                if ~isempty(app.good_c)
                    
                    % find index of this file in app.good_c
                    index1 = find(app.good_c==string(cur_filename));
                    
                    % if this file is in app.good_c, delete it in
                    % app.good_c
                    if ~isempty(index1)
                        app.good_c(index1) = [];
                    end
                    
                    % update good_c.txt
                    fileID = fopen('good_cardiac.txt', 'w');
                    fprintf(fileID,'%s \n',app.good_c{:});
                    fclose(fileID);
                    
                end
                
                % check that app.bad_c is not empty
                if ~isempty(app.bad_c)
                    
                    % find index of this file in app.bad_c
                    index2 = find(app.bad_c==string(cur_filename));
                    
                    % if this file is in app.bad_c, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.bad_c(index2) = [];
                    end
                     
                    % update bad_c.txt
                    fileID = fopen('bad_cardiac.txt', 'w');
                    fprintf(fileID,'%s \n',app.bad_c{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not_c(app.i) = true;
 
        end

        % Button pushed function: GoodRespiration
        function GoodRespirationButtonPushed(app, event)
            
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the bad files
            app.good_r = [app.good_r, string(cur_filename)];
            app.good_r = unique(app.good_r,'stable');
            
            % write the array to output file 'bad.txt'
            fileID = fopen('good_respiration.txt', 'w');
            fprintf(fileID,'%s \n',app.good_r{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or unceratin
            if app.sorted_or_not_r(app.i) == true
                
                % check that app.good is not empty
                if ~isempty(app.uncertain_r)
                    
                    % find index of this file in app.good
                    index1 = find(app.uncertain_r==string(cur_filename));
                    
                    % if this file is in app.good, delete it in app.good
                    if ~isempty(index1)
                        app.uncertain_r(index1) = [];
                    end
                    
                    % update good.txt
                    fileID = fopen('uncertain_respiration.txt', 'w');
                    fprintf(fileID,'%s \n',app.uncertain_r{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.bad_r)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.bad_r==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.bad_r(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('bad_respiration.txt', 'w');
                    fprintf(fileID,'%s \n',app.bad_r{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not_r(app.i) = true;
        end

        % Button pushed function: UncertainRespiration
        function UncertainRespirationButtonPushed(app, event)
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the bad files
            app.uncertain_r = [app.uncertain_r, string(cur_filename)];
            app.uncertain_r = unique(app.uncertain_r,'stable');
            
            % write the array to output file 'bad.txt'
            fileID = fopen('uncertain_respiration.txt', 'w');
            fprintf(fileID,'%s \n',app.uncertain_r{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or unceratin
            if app.sorted_or_not_r(app.i) == true
                
                % check that app.good is not empty
                if ~isempty(app.good_r)
                    
                    % find index of this file in app.good
                    index1 = find(app.good_r==string(cur_filename));
                    
                    % if this file is in app.good, delete it in app.good
                    if ~isempty(index1)
                        app.good_r(index1) = [];
                    end
                    
                    % update good.txt
                    fileID = fopen('good_respiration.txt', 'w');
                    fprintf(fileID,'%s \n',app.good_r{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.bad_r)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.bad_r==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.bad_r(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('bad_respiration.txt', 'w');
                    fprintf(fileID,'%s \n',app.bad_r{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not_r(app.i) = true;
        end

        % Button pushed function: BadRespiration
        function BadRespirationButtonPushed(app, event)
            % get current filename using iterator
            cur_filename = char(app.filenames(1, app.i));
            cur_filename = cur_filename(1:13);
            
            % construct an unique array to store all the bad files
            app.bad_r = [app.bad_r, string(cur_filename)];
            app.bad_r = unique(app.bad_r,'stable');
            
            % write the array to output file 'bad.txt'
            fileID = fopen('bad_respiration.txt', 'w');
            fprintf(fileID,'%s \n',app.bad_r{:});
            fclose(fileID);
            
            % delete this file in bad and uncertain txt if it has already
            % been placed in good or unceratin
            if app.sorted_or_not_r(app.i) == true
                
                % check that app.good is not empty
                if ~isempty(app.uncertain_r)
                    
                    % find index of this file in app.good
                    index1 = find(app.uncertain_r==string(cur_filename));
                    
                    % if this file is in app.good, delete it in app.good
                    if ~isempty(index1)
                        app.uncertain_r(index1) = [];
                    end
                    
                    % update good.txt
                    fileID = fopen('uncertain_respiration.txt', 'w');
                    fprintf(fileID,'%s \n',app.uncertain_r{:});
                    fclose(fileID);
                    
                end
                
                % check that app.uncertain is not empty
                if ~isempty(app.good_r)
                    
                    % find index of this file in app.uncertain
                    index2 = find(app.good_r==string(cur_filename));
                    
                    % if this file is in app.uncertain, delete it in
                    % app.uncertain
                    if ~isempty(index2)
                       app.good_r(index2) = [];
                    end
                     
                    % update uncertain.txt
                    fileID = fopen('good_respiration.txt', 'w');
                    fprintf(fileID,'%s \n',app.good_r{:});
                    fclose(fileID);
                 end

            end
            
            % denote app.i as sorted
            app.sorted_or_not_r(app.i) = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GoodButton = uibutton(app.UIFigure, 'push');
            app.GoodButton.ButtonPushedFcn = createCallbackFcn(app, @GoodButtonPushed, true);
            app.GoodButton.Text = 'Good';
            app.BadButton = uibutton(app.UIFigure, 'push');
            app.BadButton.ButtonPushedFcn = createCallbackFcn(app, @BadButtonPushed, true);
            app.BadButton.Text = 'Bad';
            app.UncertainButton = uibutton(app.UIFigure, 'push');
            app.UncertainButton.ButtonPushedFcn = createCallbackFcn(app, @UncertainButtonPushed, true);
            app.UncertainButton.Text = 'Uncertain';
            app.Image = uiimage(app.UIFigure);
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.InputSubjectNoEditFieldLabel = uilabel(app.UIFigure);
            app.InputSubjectNoEditFieldLabel.Text = 'Input Subject No.';
            app.InputSubjectNoEditField = uieditfield(app.UIFigure, 'text');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.CardiacLabel = uilabel(app.UIFigure);
            app.CardiacLabel.Text = 'Cardiac';
            app.GoodCardiac = uibutton(app.UIFigure, 'push');
            app.GoodCardiac.ButtonPushedFcn = createCallbackFcn(app, @GoodCardiacButtonPushed, true);
            app.GoodCardiac.Text = 'Good';
            app.UncertainCardiac = uibutton(app.UIFigure, 'push');
            app.UncertainCardiac.ButtonPushedFcn = createCallbackFcn(app, @UncertainCardiacButtonPushed, true);
            app.UncertainCardiac.Text = 'Uncertain';
            app.BadCardiac = uibutton(app.UIFigure, 'push');
            app.BadCardiac.ButtonPushedFcn = createCallbackFcn(app, @BadCardiacButtonPushed, true);
            app.BadCardiac.Text = 'Bad';
            app.RespirationLabel = uilabel(app.UIFigure);
            app.RespirationLabel.Text = 'Respiration';
            app.GoodRespiration = uibutton(app.UIFigure, 'push');
            app.GoodRespiration.ButtonPushedFcn = createCallbackFcn(app, @GoodRespirationButtonPushed, true);
            app.GoodRespiration.Text = 'Good';
            app.UncertainRespiration = uibutton(app.UIFigure, 'push');
            app.UncertainRespiration.ButtonPushedFcn = createCallbackFcn(app, @UncertainRespirationButtonPushed, true);
            app.UncertainRespiration.Text = 'Uncertain';
            app.BadRespiration = uibutton(app.UIFigure, 'push');
            app.BadRespiration.ButtonPushedFcn = createCallbackFcn(app, @BadRespirationButtonPushed, true);
            app.BadRespiration.Text = 'Bad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_no_load

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end